classdef aCa3_ROIs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        displaycontrolPanel             matlab.ui.container.Panel
        stepsize4Slider2                matlab.ui.control.NumericEditField
        stepsizeLabel                   matlab.ui.control.Label
        Slider2                         matlab.ui.control.RangeSlider
        Slider2Label                    matlab.ui.control.Label
        getlimitsButton                 matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        singlefileButton                matlab.ui.control.RadioButton
        singleROIButton                 matlab.ui.control.RadioButton
        allButton                       matlab.ui.control.RadioButton
        iFileLabel                      matlab.ui.control.Label
        iFileSpinner_2                  matlab.ui.control.Spinner
        iROISpinner_2                   matlab.ui.control.Spinner
        iROISpinner_2Label              matlab.ui.control.Label
        xendEditField                   matlab.ui.control.NumericEditField
        xendEditFieldLabel              matlab.ui.control.Label
        xstartEditField                 matlab.ui.control.NumericEditField
        xstartEditFieldLabel            matlab.ui.control.Label
        globalsettingsPanel             matlab.ui.container.Panel
        projectionTypeButtonGroup       matlab.ui.container.ButtonGroup
        stdButton                       matlab.ui.control.RadioButton
        maxButton                       matlab.ui.control.RadioButton
        meanButton                      matlab.ui.control.RadioButton
        toworkspaceButton               matlab.ui.control.Button
        fromworkspaceButton             matlab.ui.control.Button
        maxprojectionButton             matlab.ui.control.Button
        projectionSourceButtonGroup     matlab.ui.container.ButtonGroup
        DFoF0Button                     matlab.ui.control.RadioButton
        mocoButton                      matlab.ui.control.RadioButton
        rawButton                       matlab.ui.control.RadioButton
        displayPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ROITab                          matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        dirEditField                    matlab.ui.control.EditField
        dirEditFieldLabel               matlab.ui.control.Label
        matfilesListBox                 matlab.ui.control.ListBox
        matfilesListBoxLabel            matlab.ui.control.Label
        loadMatFileDir                  matlab.ui.control.Button
        maxNroisEditField               matlab.ui.control.NumericEditField
        deadzonepixelsLabel_4           matlab.ui.control.Label
        bonusPixelsEditField            matlab.ui.control.NumericEditField
        deadzonepixelsLabel_3           matlab.ui.control.Label
        moveROIstepSpinner              matlab.ui.control.Spinner
        movementsteppixelsSpinnerLabel  matlab.ui.control.Label
        penumbraPixelsEditField         matlab.ui.control.NumericEditField
        deadzonepixelsLabel_2           matlab.ui.control.Label
        moveROIsButton                  matlab.ui.control.Button
        newROIsButton                   matlab.ui.control.Button
        startROIButton                  matlab.ui.control.Button
        deadzonePixelsEditField         matlab.ui.control.NumericEditField
        deadzonepixelsLabel             matlab.ui.control.Label
        radiusPixelsEditField           matlab.ui.control.NumericEditField
        radiuspixelsEditFieldLabel      matlab.ui.control.Label
        ROItypeButtonGroup              matlab.ui.container.ButtonGroup
        structuredButton                matlab.ui.control.RadioButton
        roundButton                     matlab.ui.control.RadioButton
        saveROIsButton                  matlab.ui.control.Button
        showROIsButton                  matlab.ui.control.Button
        structureROIsButton             matlab.ui.control.Button
        loadTab                         matlab.ui.container.Tab
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        intervalmsEditField             matlab.ui.control.NumericEditField
        intervalmsEditFieldLabel        matlab.ui.control.Label
        loadcompleteButton              matlab.ui.control.Button
        loadETSButton                   matlab.ui.control.Button
        mocoTab                         matlab.ui.container.Tab
        boundspixelsEditField           matlab.ui.control.NumericEditField
        binwidthframesLabel_3           matlab.ui.control.Label
        MocotemplatesButton             matlab.ui.control.Button
        mocochunkSpinner                matlab.ui.control.Spinner
        mocochunkSpinnerLabel           matlab.ui.control.Label
        MoCoTypeButtonGroup             matlab.ui.container.ButtonGroup
        nonrigidButton                  matlab.ui.control.RadioButton
        rigidButton                     matlab.ui.control.RadioButton
        MoCoDirectionsButtonGroup       matlab.ui.container.ButtonGroup
        reverseforwardButton            matlab.ui.control.RadioButton
        forwardreverseButton            matlab.ui.control.RadioButton
        reverseButton                   matlab.ui.control.RadioButton
        forwardButton                   matlab.ui.control.RadioButton
        chunkSizeEditField              matlab.ui.control.NumericEditField
        chunksizeframesLabel            matlab.ui.control.Label
        gridSizeEditField               matlab.ui.control.NumericEditField
        binwidthframesLabel_2           matlab.ui.control.Label
        binwidthEditField               matlab.ui.control.NumericEditField
        binwidthframesLabel             matlab.ui.control.Label
        saveMoCofilteredstackCheckBox   matlab.ui.control.CheckBox
        maxshiftEditField               matlab.ui.control.NumericEditField
        maxshiftpixelsEditFieldLabel    matlab.ui.control.Label
        frameaverageEditField           matlab.ui.control.NumericEditField
        frameaverageEditFieldLabel      matlab.ui.control.Label
        confirmMoCoButton               matlab.ui.control.Button
        mocoConfirmSlider               matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        MoCoButton                      matlab.ui.control.Button
        MoCographsButton                matlab.ui.control.Button
        stackviewerTab                  matlab.ui.container.Tab
        penumbraEditField               matlab.ui.control.NumericEditField
        penumbraEditFieldLabel          matlab.ui.control.Label
        pensubEditField                 matlab.ui.control.NumericEditField
        pensubLabel                     matlab.ui.control.Label
        ROIEditField                    matlab.ui.control.NumericEditField
        ROIEditFieldLabel               matlab.ui.control.Label
        frameEditField                  matlab.ui.control.NumericEditField
        frameEditFieldLabel             matlab.ui.control.Label
        showROIsCheckBox_2              matlab.ui.control.CheckBox
        imageminmaxSlider               matlab.ui.control.RangeSlider
        imageminmaxSliderLabel          matlab.ui.control.Label
        stackSelectDropDown             matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        frameaverageEditField_2         matlab.ui.control.NumericEditField
        frameaverageEditField_2Label    matlab.ui.control.Label
        stackSlider                     matlab.ui.control.Slider
        Slider_2Label                   matlab.ui.control.Label
        viewStackButton                 matlab.ui.control.Button
        dFF0Tab                         matlab.ui.container.Tab
        fractionPenumbraEditField       matlab.ui.control.NumericEditField
        minminBFrawFlLabel              matlab.ui.control.Label
        confirmButton                   matlab.ui.control.Button
        t4largeeventdurationsEditField  matlab.ui.control.NumericEditField
        t4largeeventdurationsEditFieldLabel  matlab.ui.control.Label
        notimplementedt3sEditField      matlab.ui.control.NumericEditField
        notimplementedt3sEditFieldLabel  matlab.ui.control.Label
        t2minsearchsEditField           matlab.ui.control.NumericEditField
        t2minsearchsEditFieldLabel      matlab.ui.control.Label
        t1smoothsEditField              matlab.ui.control.NumericEditField
        t1smoothsEditFieldLabel         matlab.ui.control.Label
        dFoF0Button                     matlab.ui.control.Button
        updateROIdataButton             matlab.ui.control.Button
        datafromROIsButton              matlab.ui.control.Button
        detectTab                       matlab.ui.container.Tab
        postsSpinner                    matlab.ui.control.Spinner
        postsSpinnerLabel               matlab.ui.control.Label
        presSpinner                     matlab.ui.control.Spinner
        presSpinnerLabel                matlab.ui.control.Label
        plotButton                      matlab.ui.control.Button
        stopfreqHzSpinner               matlab.ui.control.Spinner
        stopfreqHzLabel                 matlab.ui.control.Label
        orderSpinner                    matlab.ui.control.Spinner
        orderSpinnerLabel               matlab.ui.control.Label
        samplerateHzEditField           matlab.ui.control.NumericEditField
        samplerateHzEditFieldLabel      matlab.ui.control.Label
        filterButton                    matlab.ui.control.Button
        detectButton                    matlab.ui.control.Button
        minwidthsSpinner                matlab.ui.control.Spinner
        minwidthsSpinnerLabel           matlab.ui.control.Label
        SDfactorSpinner                 matlab.ui.control.Spinner
        SDfactorSpinnerLabel            matlab.ui.control.Label
        SDpercentileSpinner             matlab.ui.control.Spinner
        SDpercentileSpinnerLabel        matlab.ui.control.Label
        SDrollsSpinner                  matlab.ui.control.Spinner
        SDrollsSpinnerLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ROIdatadirButton                matlab.ui.control.Button
        eventsTab                       matlab.ui.container.Tab
        postSpinner                     matlab.ui.control.Spinner
        postSpinnerLabel                matlab.ui.control.Label
        preSpinner                      matlab.ui.control.Spinner
        preSpinnerLabel                 matlab.ui.control.Label
        getvarsTab                      matlab.ui.container.Tab
        loadvarsButton                  matlab.ui.control.Button
        checkvarsButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        % Description
        S % struct from loading ETS and resulting output
        stack
        timing
        baseInterval
        baseName
        proj
        ax
        im
        analytes
        varsToLoad
        varValues
        useBaseStats
        detectTabStatus
        moveROIstatus = 0
        tlayout
        MoCoDispStatus = 'none'
        m =[] % matfile
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.ROIdata = ROIdata;
            % app.analytes = analytes;
        end

        % Button pushed function: loadETSButton
        function loadETSButtonPushed2(app, event)
            C = app.Lamp.Color;
            app.Lamp.Color = [1,0,0]; drawnow

            app.S = openETSui2(); % S struct contains [ stack, timing, interval, name, ets, metadata ]
            % adjust moco settings
            app.chunkSizeEditField.Value = size(app.S.stack,3); % default the chunk size to all frames
            app.binwidthEditField.Value = bestDivisor(app.chunkSizeEditField.Value,app.binwidthEditField.Value);

            if app.S.interval > 5
                app.S.interval = app.S.interval/1000;
                disp( 'loadETS, interval seems off, converting to sec' )
            end
            app.Lamp.Color = C; drawnow
            app.nameEditField.Value = app.S.name;
            app.intervalmsEditField.Value = app.S.interval;

            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            app.proj = MeanMaxSTD( app.S.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
            app.im = imshow(app.proj,'Parent',app.ax);
            clim(app.ax, 'auto')
            app.m = [];
            % vars loaded into app.S
            % S.stack = stack;
            % S.timing = timing;
            % S.interval = interval;
            % S.name = name;
            % S.ets = ets;
            % S.metadata = metadata;
        end

        % Button pushed function: loadcompleteButton
        function loadcompleteButtonPushed(app, event)
            % load vars from _complete.mat file
            % vars needed: base and ROIs
            C = app.Lamp.Color;
            app.Lamp.Color = [1,0,0]; drawnow
            [file,path] = uigetfile('*complete*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            app.S = loadComplete3( fullfile( path, file ) );

            % adjust moco settings
            app.chunkSizeEditField.Value = size(app.S.stack,3); % default the chunk size to all frames
            app.binwidthEditField.Value = bestDivisor(app.chunkSizeEditField.Value,app.binwidthEditField.Value);

            if app.S.interval > 5
                app.S.interval = app.S.interval/1000;
                disp( 'load complete button, interval seems off, converting to sec' )
            end

            app.Lamp.Color = [0,1,0]; drawnow

            app.nameEditField.Value = app.S.name;
            app.intervalmsEditField.Value = app.S.interval;

            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] ); %[0 0 1 1]);
            %app.ax = uiaxes(app.displayPanel, 'Units', 'normalized', 'Position', [0 0 1 1]);
            app.proj = MeanMaxSTD( app.S.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
            app.im = imshow(app.proj,'Parent',app.ax);
            clim(app.ax, 'auto')
            app.m = [];
            disp('done loading complete...')

            % vars loaded into app.S using loadComplete:
            % S.stack = temp.base;
            % S.timing = temp.timing;
            % S.interval = temp.baseInterval;
            % S.name = temp.baseName;
            % S.filename = temp.filename; % complete.mat
            % S.ets = temp.baseETS;
            % %S.metadata = temp.metadata;
            % S.hROI = temp.hROI;
            % S.group = temp.group;
        end

        % Button pushed function: maxprojectionButton
        function maxprojectionButtonPushed2(app, event)
            disp('in max project2...')
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            if (app.mocoButton.Value == 1) && isfield(app.S,'Smoco')
                app.proj = MeanMaxSTD( app.S.Smoco.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
            else
                app.proj = MeanMaxSTD( app.S.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
                app.mocoButton.Value = 0;
            end
            app.im = imshow(app.proj,'Parent',app.ax);
            clim(app.ax, 'auto')
        end

        % Button pushed function: MoCoButton
        function MoCoButtonPushed2(app, event)
            C = app.Lamp.Color;
            app.Lamp.Color = [1,0,0]; drawnow

            bounds = app.boundspixelsEditField.Value;
            chunkSize = app.chunkSizeEditField.Value;
            maxShift = app.maxshiftEditField.Value;
            saveY = app.saveMoCofilteredstackCheckBox.Value;
            binWidth = app.binwidthEditField.Value;
            gridSize = app.gridSizeEditField.Value;

            switch app.MoCoTypeButtonGroup.SelectedObject.Text
                case 'rigid'
                    rigid = 1;
                case 'non-rigid'
                    rigid = 0;
                otherwise
                    disp('moco type button group failure')
                    return
            end
            if app.mocoButton.Value == 1
                mstack = app.S.Smoco.stack;
                disp('using moco stack for moco')
            else
                mstack = app.S.stack;
                disp('using raw stack for moco')
            end

            nFrames = size(mstack,3);
            if chunkSize == 0
                nChunks = 1;
            else
                nChunks = ceil( nFrames / chunkSize );
            end

            switch app.MoCoDirectionsButtonGroup.SelectedObject.Text
                case 'reverse+forward'
                    %reverse = 3;
                case 'foward+reverse'
                    %reverse = 2;
                case 'reverse'
                    for i=1:nChunks
                        chunkStart = nFrames - (i-1)*chunkSize; % start at the end
                        if i == nChunks
                            chunkEnd = 1; % do not pass go!
                        else
                            chunkEnd = nFrames - i*chunkSize + 1; % move back one chunk
                        end
                        disp(append(num2str(chunkStart),' ',num2str(chunkEnd)))
                        chunk = mstack( :, :, chunkStart:-1:chunkEnd ); %app.S.stack( :, :, chunkStart:-1:chunkEnd ); % reversing time, chunk is backwards

                        if i ~= 1
                            % lets create temporal overlap
                            % get the "end" of the last alignment for new template
                            sz = size( Smoco(i-1).stack, 3 );
                            chunk_template = Smoco(i-1).stack( :, :, sz-binWidth+1 : sz ); % takes the end of the last alignment (already reversed)
                            % tack on the reversed chunk from the raw data
                            chunk = cat( 3, chunk_template, chunk );
                        end

                        Smoco(i) = td_moco2( ...
                            chunk, ...
                            gridSize, maxShift, binWidth, rigid, ...
                            saveY );

                        if i==1
                            app.S.Smoco.stack = flip(Smoco(i).stack,3);
                        else
                            sz = size( Smoco(i).stack, 3 );
                            app.S.Smoco.stack = ...
                                cat( 3, ...
                                Smoco(i).stack(:,:,sz:-1:(binWidth+1) ), ... % reverse the backwards chunk
                                app.S.Smoco.stack ...
                                );
                        end

                    end % loop over chunks

                case 'forward'

                    for i=1:nChunks
                        chunkStart = 1 + (i-1)*chunkSize;
                        if i == nChunks
                            chunkEnd = nFrames; % stop at the end
                        else
                            chunkEnd = i*chunkSize;
                        end
                        chunk = mstack( :, :, chunkStart:chunkEnd ); % app.S.stack( :, :, chunkStart:chunkEnd );
                        if i ~= 1
                            % lets create temporal overlap
                            sz = size( Smoco(i-1).stack, 3 );
                            chunk_template = Smoco(i-1).stack(:,:, sz-binWidth+1:sz);
                            chunk = cat( 3, chunk_template, chunk );
                        end
                        %% TD_MOCO! %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        Smoco(i) = td_moco2( ...
                            chunk, ...
                            bounds, gridSize, maxShift, binWidth, rigid, ...
                            saveY );
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if isempty(Smoco(i).stack)
                            disp('td_moco2 FAILED!')
                            return
                        end
                        if i==1
                            app.S.Smoco = [];
                            app.S.Smoco.stack = Smoco(i).stack;
                            app.S.Smoco.S(i).stack = Smoco(i).stack;
                        else
                            sz = size( Smoco(i).stack, 3 );
                            app.S.Smoco.stack = ...
                                cat( 3, ...
                                app.S.Smoco.stack, ...
                                Smoco(i).stack(:,:,(binWidth+1):sz) ...
                                );
                            app.S.Smoco.S(i).stack = Smoco(i).stack(:,:,(binWidth+1):sz);
                        end

                        app.S.Smoco.S(i).template = Smoco(i).template;
                        app.S.Smoco.S(i).options = Smoco(i).options;
                        app.S.Smoco.S(i).shifts = Smoco(i).shifts;
                        app.S.Smoco.S(i).T = Smoco(i).T;
                        app.S.Smoco.S(i).cY = Smoco(i).cY;
                        app.S.Smoco.S(i).cM1 = Smoco(i).cM1;
                        app.S.Smoco.S(i).cMr = Smoco(i).cMr;
                        app.S.Smoco.S(i).cYraw = Smoco(i).cYraw;
                    end % loop over chunks

                otherwise
                    disp('moco directions button group failure')
                    disp(app.MoCoDirectionsButtonGroup.SelectedObject.Text)
                    return
            end
            app.mocochunkSpinner.Limits = [ 1, nChunks];
            disp( 'forward moco complete')
            app.Lamp.Color = C; drawnow
            MoCographsButtonPushed2(app, event)
            % Smoco returns options, rigid shifts and metrics, and aligned stack
            % Smoco.options_r = options_r;
            % Smoco.shifts_r = shifts_r;
            % Smoco.T = T;
            % Smoco.cY = cY;
            % Smoco.cM1 = cM1;
            % Smoco.cYraw = cYraw;
            % Smoco.cM1f = cM1f;
            % Smoco.stack = Mr; % this is the motion corrected stack
        end

        % Button pushed function: MoCographsButton
        function MoCographsButtonPushed2(app, event)
            delete(app.displayPanel.Children)
            t = tiledlayout(app.displayPanel,3,1);
            i = app.mocochunkSpinner.Value;
            ax1 = nexttile(t);
            if isfield(app.S.Smoco,"shifts") % single chunks don't have substructures
                plot( ax1, app.S.Smoco.shifts );
                title( ax1, 'Rigid shifts','fontsize',14,'fontweight','bold');
                legend( ax1, 'y-shifts','x-shifts');
                ax2 = nexttile(t);
                plot( ax2, 1:app.S.Smoco.T, app.S.Smoco.cY, 1:app.S.Smoco.T, app.S.Smoco.cM1 );
                title( ax2,'Correlation coefficients on spatial filtered movie','fontsize',14,'fontweight','bold');
                legend(ax2,'raw','rigid');
                ax3 = nexttile(t);
                plot(ax3, 1:app.S.Smoco.T, app.S.Smoco.cYraw, 1:app.S.Smoco.T, app.S.Smoco.cMr );
                title(ax3, 'Correlation coefficients on raw movie','fontsize',14,'fontweight','bold');
                legend(ax3, 'raw','rigid');
            else % handle chunks
                plot( ax1, app.S.Smoco.S(i).shifts );
                title( ax1, 'Rigid shifts','fontsize',14,'fontweight','bold');
                legend( ax1, 'y-shifts','x-shifts');
                ax2 = nexttile(t);
                plot( ax2, 1:app.S.Smoco.S(i).T, app.S.Smoco.S(i).cY, 1:app.S.Smoco.S(i).T, app.S.Smoco.S(i).cM1 );
                title( ax2,'Correlation coefficients on spatial filtered movie','fontsize',14,'fontweight','bold');
                legend(ax2,'raw','rigid');
                ax3 = nexttile(t);
                plot(ax3, 1:app.S.Smoco.S(i).T, app.S.Smoco.S(i).cYraw, 1:app.S.Smoco.S(i).T, app.S.Smoco.S(i).cMr );
                title(ax3, 'Correlation coefficients on raw movie','fontsize',14,'fontweight','bold');
                legend(ax3, 'raw','rigid');
            end
            app.MoCoDispStatus = 'graphs';
        end

        % Button pushed function: toworkspaceButton
        function toworkspaceButtonPushed2(app, event)
            disp('move to workspace!')
            varn = 'S';
            val = app.S;
            assignin("base",varn,val)
        end

        % Button pushed function: showROIsButton
        function showROIsButtonPushed(app, event)
           
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );

            projection_type = app.projectionTypeButtonGroup.SelectedObject.Text;
            projection_source = app.projectionSourceButtonGroup.SelectedObject.Text;
            app.proj = app.S.projections.(projection_source).(projection_type);

            app.im = imshow(app.proj,'Parent',app.ax);
            crop_pixels = 10;
            signal_range = cleanDisplayRange( app.proj, crop_pixels ); % [min_signal,max_signal];

            clim(app.ax, signal_range)

            % if COMBO exists, show it!
            go=false;
            if isfield(app.S,"hROI")
                if length(app.S.hROI)>1
                    app.S.hROI(2)=[];
                end
                if isfield( app.S.hROI,"combo")
                    projection_type="combo";
                    go=true;
                else
                    if isfield( app.S.hROI, projection_type )
                        go=true;
                    end
                end
            end
            if go
                temp = drawROI( app.S.hROI.(projection_type), app.ax );
            else
                disp('NO ROIS!')
            end
        end

        % Button pushed function: structureROIsButton
        function structuredROIsButtonPushed(app, event)
            % pos = app.displayPanel.Position;
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            
            projection_type = app.projectionTypeButtonGroup.SelectedObject.Text;
                        projection_source = app.projectionSourceButtonGroup.SelectedObject.Text;
                        

            if isfield( app.S.hROI, projection_type )
                app.proj = app.S.projections.(projection_source).(projection_type);

                app.im = imshow(app.proj,'Parent',app.ax);
                clim(app.ax, 'auto')

                r = app.radiusPixelsEditField.Value; % 10 pixels!!
                deadzone = app.deadzonePixelsEditField.Value; % 4 pixels!!
                penumbra = app.penumbraPixelsEditField.Value;
                bonus = app.bonusPixelsEditField.Value;
                h2 = structureROIs( app.S.hROI.(projection_type), app.ax, app.proj, r, deadzone, penumbra, bonus );
                app.S.hROI.(projection_type) = h2;
            else
                disp(append("structured ROIS, no hROI for type: ", projection_type))
            end

        end

        % Button pushed function: datafromROIsButton
        function datafromROIsButtonPushed(app, event)

            app.Lamp.Color = [1,0,0]; drawnow
            disp('data from ROI button pushed!')
            status = app.mocoButton.Value;
            fracPen = app.fractionPenumbraEditField.Value;
            if logical(status) % app.mocoButton
                disp('ROI data from MoCo traces')
                app.S.baseStats = getROItraces2( app.S.Smoco.stack, app.S.hROI, fracPen ); % uses "raw" (cropped, smoothed etc)
            else
                disp('ROI data from raw traces')
                app.S.baseStats = getROItraces2( app.S.stack, app.S.hROI, fracPen ); % uses "raw" (cropped, smoothed etc)
            end
            disp('data from ROI button pushed! ROIstats done!')
            app.Lamp.Color = [0,1,0]; drawnow

            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            hold( app.ax, 'on' )
            dx = mean(diff(app.S.timing));
            if dx>5
                disp('timing data in msec...converting to sec');
                app.S.timing = app.S.timing/1000;
            end
            for i=1:size(app.S.baseStats.ROI,2) % number of ROIs
                plot( app.ax, app.S.timing, app.S.baseStats.ROI(i).pensub )
                %plot( app.ax, app.S.baseStats.ROI(i).pensub )
                %plot(ROIstats.ROI(i).deadzone.meanZ)
                %plot(ROIstats.ROI(i).penumbra.meanZ)
            end
            xlabel( app.ax, 'time (seconds)')
            ylabel( app.ax, 'mean fluorescence')
            disp('done with ROI stats')
        end

        % Button pushed function: dFoF0Button
        function dFoF0ButtonPushed(app, event)
            app.S.tn = [ app.t1smoothsEditField.Value, app.t2minsearchsEditField.Value, app.notimplementedt3sEditField.Value, app.t4largeeventdurationsEditField.Value ];
            % user units are seconds!
            % convert to frames
            % check interval! might be in msec
                        dx = mean(diff(app.S.timing));
            if dx>5
                disp('timing data in msec...converting to sec');
                app.S.timing = app.S.timing/1000;
            end
            tn_frames = app.S.tn / mean(diff(app.S.timing)); % 911

            app.S.baseStats = processDFoF0( app.S.baseStats, tn_frames(1), tn_frames(2), tn_frames(4) );% , minminBF );

            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            hold( app.ax, 'on' )
                        dx = mean(diff(app.S.timing));
            if dx>5
                disp('timing data in msec...converting to sec');
                app.S.timing = app.S.timing(1:nel)/1000;
            end
            for i=1:size(app.S.baseStats.ROI,2)
                plot( app.ax, app.S.timing, app.S.baseStats.ROI(i).dFoF0.pensub, ...
                    'DisplayName', append( 'ROI', num2str(i)) )
            end
            legend( app.ax )
            xlabel( app.ax, 'time (seconds)')
            ylabel( app.ax, 'dF/F0')
        end

        % Button pushed function: checkvarsButton
        function checkvarsButtonPushed(app, event)
            % based on: https://www.mathworks.com/matlabcentral/answers/504407-numeric-editfield-in-app-designer#answer_414538
            % Before calling the whos function from evalin, we'll do a
            % security check that the whos function is the one provided
            % by Matlab.  This reduces the risk of using evalin().
            if isempty(regexp(which('whos'), 'built-in *.'))
                error('The built-in whos() function is being overshaddowed.  Do not proceed.')
            end
            % Get base workspace variable info
            baseVarInfo = evalin('base', 'whos');
            % Convert to table
            rowNames = {baseVarInfo.name};
            baseVarInfo = rmfield(baseVarInfo,{'name','nesting','global','sparse','complex','persistent'});
            baseVarCell = [num2cell(false(size(baseVarInfo))),struct2cell(baseVarInfo)']; % selection column
            varNames = [{'Select'}; fieldnames(baseVarInfo)];
            baseVarTable = cell2table(baseVarCell, 'RowNames', rowNames, 'VariableNames', varNames);

            delete(app.displayPanel.Children)
            fig = app.displayPanel; % uifigure(app.displayPanel);
            uit = uitable(fig, 'position', [20 20 500, 300],...
                'ColumnEditable', [true, false(1,numel(varNames)-1)], 'Tag', 'BaseWorkspaceVarInfo');
            uit.Data = baseVarTable;
        end

        % Button pushed function: loadvarsButton
        function loadvarsButtonPushed(app, event)
            % based on: https://www.mathworks.com/matlabcentral/answers/504407-numeric-editfield-in-app-designer#answer_414538
            % Find the handle to the UITable (note the tag name assigned to the UITable)
            % This step is avoidable if the UITable handle is already stored in app.
            uitab = findall(0, 'Tag', 'BaseWorkspaceVarInfo');
            % Get the user's selection and extract the variable names (varsToLoad).
            selection = uitab.Data.Select;
            app.varsToLoad = uitab.RowName(selection);
            % loop through each variable and store it's values in a cell array.
            app.varValues = cell(size(app.varsToLoad));
            for i = 1:numel(app.varsToLoad)
                app.varValues{i} = evalin('base', app.varsToLoad{i});
            end
            app.ROIdata = cell2struct(app.varValues(1));
            app.analytes = cell2struct(app.varValues(2));
        end

        % Button pushed function: ROIdatadirButton
        function ROIdatadirButtonPushed(app, event)
            % DETECT - READ IGOR MULTI-EXP FILE
            [file,path] = uigetfile('*_*gor*.mat');
            if isequal(file,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            %ROI_dir = fullfile( path, file );
            app.EditField.Value = path;
            app.S.ROIdata = loadROIdata2( path );
            nFiles = size( app.S.ROIdata, 2 );
            iFile  = 1;
            iROI = 1;
            nROIs = size( app.S.ROIdata(iFile).data, 2 );
            app.iFileSpinner_2.Limits = [1,nFiles];
            app.iFileSpinner_2.Value = iFile;
            app.iROISpinner_2.Limits = [1,nROIs];
            app.iROISpinner_2.Value = iROI;
            app.samplerateHzEditField.Value = 1/mean(diff(app.S.ROIdata(iFile).xdata));
            filterButtonPushed(app,event)
            app.detectTabStatus = 'igor';
        end

        % Value changed function: iFileSpinner_2
        function iFileSpinner_2ValueChanged(app, event)
            if strcmp(app.detectTabStatus,'detect')
                plotButtonPushed(app, event)
            else
                filterButtonPushed(app,event)
            end
        end

        % Callback function: filterButton, orderSpinner, stopfreqHzSpinner
        function filterButtonPushed(app, event)

            Fstop = app.stopfreqHzSpinner.Value;
            Fsampling = app.samplerateHzEditField.Value;
            Niir = app.orderSpinner.Value;

            nFiles = app.iFileSpinner_2.Limits(2);
            delete(app.displayPanel.Children)
            % process all files, all ROI
            for iFile = 1:nFiles
                % filtering happens here!

                app.S.ROIdata(iFile).fdata = filterData( app.S.ROIdata(iFile).data, Fsampling, Niir, Fstop);

                % the rest is plotting
                if ( iFile == app.iFileSpinner_2.Value ) && ( app.allButton.Value == 1 )
                    nROIs = size(app.S.ROIdata(iFile).data,2);
                    t = tiledlayout( app.displayPanel, nROIs, 1 );
                    t.TileSpacing = 'tight';
                    for iROI=1:nROIs
                        app.ax(iROI) = nexttile(t);
                        plot(app.ax(iROI), app.S.ROIdata(iFile).xdata, app.S.ROIdata(iFile).data(:,iROI), ...
                            app.S.ROIdata(iFile).xdata, app.S.ROIdata(iFile).fdata(:,iROI))
                        app.ax(iROI).XAxis.Visible = 'off';
                    end
                    app.ax(iROI).XAxis.Visible = 'on';
                end
            end
            if app.allButton.Value == 0
                % process selected file and ROI
                iFile = app.iFileSpinner_2.Value;
                iROI = app.iROISpinner_2.Value;
                app.ax = uiaxes( app.displayPanel, 'Units', 'normalized', 'Position', [0 0 1 1] );
                hold( app.ax, 'on' )
                plot( app.ax, app.S.ROIdata(iFile).xdata, app.S.ROIdata(iFile).data( :, iROI ), ...
                    app.S.ROIdata(iFile).xdata, app.S.ROIdata(iFile).fdata( :, iROI ) )
                xlim( app.ax, [app.xstartEditField.Value, app.xendEditField.Value] )
            end
            % display selection

            app.detectTabStatus = 'filter';
            detectButtonPushed(app,event)
        end

        % Value changed function: iROISpinner_2
        function iROISpinner_2ValueChanged(app, event)
            %value = app.iROISpinner_2.Value;
            thisTab = app.TabGroup.SelectedTab;

            switch thisTab.Title
                case 'detect'
                    if strcmp(app.detectTabStatus, 'detect')
                        plotButtonPushed(app, event)
                    else
                        filterButtonPushed(app,event)
                    end
                case 'dF/F0'
                    confirmButtonPushed(app,event)
                otherwise
            end
        end

        % Button pushed function: detectButton
        function detectButtonPushed(app, event)
            roll = app.SDrollsSpinner.Value;
            prcthresh = app.SDpercentileSpinner.Value;
            Pfactor = app.SDfactorSpinner.Value;
            minwidth = app.minwidthsSpinner.Value;
            app.S.analytes = analyzeROIs2( app.S.ROIdata, roll, minwidth, ...
                Pfactor, prcthresh);
            app.detectTabStatus = 'detect';
            plotButtonPushed(app,event)

        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            pre = app.presSpinner.Value;
            post = app.postsSpinner.Value;
            iFile = app.iFileSpinner_2.Value;
            iROI = app.iROISpinner_2.Value;
            %nROIs = size(app.S.ROIdata(iFile).data,2);
            if strcmp(app.detectTabStatus, 'detect')
                delete(app.displayPanel.Children)
                t = tiledlayout( app.displayPanel, 2, 1 );
                t.TileSpacing = 'tight';
                [ ax1, ~ ] = plotEvents( t, app.S.ROIdata, app.S.analytes, iFile, iROI, pre, post );
                xlim( ax1, [ app.xstartEditField.Value, app.xendEditField.Value ] )
            end
        end

        % Button pushed function: saveROIsButton
        function saveROIsButtonPushed(app, event)
            % save "complete" file
            % need these vars for backwards compatibility with loadComplete:
            % app vars = file vars
            % S.stack = temp.base;
            % S.timing = temp.timing;
            % S.interval = temp.baseInterval;
            % S.name = temp.baseName;
            % S.filename = temp.filename; % complete.mat
            % S.ets = temp.baseETS;
            % %S.metadata = temp.metadata;
            % S.hROI = temp.hROI;
            % S.group = temp.group;
            % need these vars for forwards compatibility
            % S.Smoco
            % check if fully loaded file
            C = app.Lamp.Color;
            app.Lamp.Color = [1,0,0]; drawnow
            if isfield(app.S,"stack")
                filename = append( app.nameEditField.Value, 'completePROJ.mat');
                app.S = app.S;
                save( filename, '-struct', 'S' ) % saves to current folder (setup defaults!)
            else
                if ~isempty(app.m)
                    app.m.hROI = app.S.hROI; % should write ALL roi info
                end
            end
            app.Lamp.Color = C; drawnow
        end

        % Button pushed function: getlimitsButton
        function getlimitsButtonPushed(app, event)
            % get the x limits of the current axis in the panel

        end

        % Button pushed function: updateROIdataButton
        function updateROIdataButtonPushed(app, event)
            % save ROIdata file for directory based analysis of events
            % previously called _Igor, now ROIdata??? not yet!
            fname = append(app.S.name, '_igor');
            export2igorAPP(app.S,fname)

        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)

            iROI = app.iROISpinner_2.Value;
            nel = max(size(app.S.timing)); % get the number of time points
            dx = mean(diff(app.S.timing));
            if dx>5
                disp('timing data in msec...converting to sec');
                app.S.timing = app.S.timing(1:nel)/1000;
            end

            delete(app.displayPanel.Children)
            t = tiledlayout( app.displayPanel, 3, 1 );
            t.TileSpacing = 'tight';
            t0 = nexttile(t);
            plot(t0, app.S.timing, app.S.baseStats.ROI(iROI).ROI.meanZ )
            hold( t0, 'on' )
            plot(t0, app.S.timing, app.S.baseStats.ROI(iROI).penumbra.meanZ )
            t0.XAxis.Visible = 'off';

            t1 = nexttile(t);
            plot(t1, app.S.timing, app.S.baseStats.ROI(iROI).pensub )
            hold( t1, 'on' )
            plot(t1, app.S.timing, app.S.baseStats.ROI(iROI).dFoF0.minBF )
            t1.XAxis.Visible = 'off';

            t2 = nexttile(t);
            plot(t2, app.S.timing, app.S.baseStats.ROI(iROI).dFoF0.pensub )
            linkaxes([t0,t1,t2],'x')
        end

        % Button pushed function: fromworkspaceButton
        function fromworkspaceButtonPushed(app, event)
            disp('move from workspace!')
            varn = 'S';

            app.S = evalin("base",varn);
        end

        % Button pushed function: newROIsButton
        function newROIsButtonPushed(app, event)
            % store the old ROIs
            if isfield( app.S, 'hROI')
                app.S.old_hROI=app.S.hROI;
                app.S.hROI=[];
                disp("destroyed ROIs, check old_hROI for backup!")
            else
                %disp('NO ROI FOR YOU! COME BACK ONE YEAR!')
            end

            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );

            projection_type = app.projectionTypeButtonGroup.SelectedObject.Text; % e.g. mean, std, max
            projection_group = app.projectionSourceButtonGroup.SelectedObject.Text;%'DFoF0';
            app.proj = app.S.projections.(projection_group).(projection_type);
            app.im = imshow(app.proj,'Parent',app.ax);
            minmax0=[0,100];
            crop_pixels = 10; % setting to ignore garbage on edges when setting range for image display

            % projection terminology differs from complete 
        signal_range = cleanDisplayRange( app.proj, crop_pixels ); % [min_signal,max_signal];


            [minmax0(1), minmax0(2)] = minmax( app.proj );
            app.Slider2.Limits = double(minmax0);
            app.Slider2.Value = double(signal_range); % double(minmax0);
            %app.Slider2.Step = double((minmax0(2)-minmax0(1))/1000);
            %disp(['slider step: ',app.Slider2.Step])% = diff(minmax0)/1000;            
            clim(app.ax, app.Slider2.Value )
            %clim(app.ax, 'auto')

            %I = facilitatedROIs( app.proj, app.ax ); % returns logical m x n array with peak locations

           
        end

        % Button pushed function: confirmMoCoButton
        function confirmMoCoButtonPushed(app, event)
            app.mocoConfirmSlider.Limits = [ 1, size( app.S.stack, 3 ) ];
            mocoConfirmSliderValueChanged(app,event)
        end

        % Value changed function: mocoConfirmSlider
        function mocoConfirmSliderValueChanged(app, event)
            frame = round(app.mocoConfirmSlider.Value);
            nave = round(app.frameaverageEditField.Value);
            delete(app.displayPanel.Children)
            %pos = app.displayPanel.Position;

            m=1;n=2;
            nFrames = size(app.S.stack,3);
            ts = frame;
            tend = frame+nave;
            if(tend>nFrames)
                tend=nFrames;
            end
            im1 = mean(app.S.stack(:,:,ts:tend),3); % average nave frames
            im2 = mean(app.S.Smoco.stack(:,:,ts:tend),3);

            app.tlayout = tiledlayout(app.displayPanel,m,n);
            nt = nexttile(app.tlayout);
            imshow( im1, 'Parent', nt )
            clim(nt,'auto')
            h = drawROI( app.S.hROI, nt );
            mystr = append('Frame: ', num2str(frame)); %sprintf('RAW Frame %i out of %i', frame, nFrames, 'fontweight', 'bold', 'fontsize', 14 );
            title( nt, mystr );
            drawnow;
            nt2 = nexttile(app.tlayout);
            imshow( im2, 'Parent', nt2)
            clim(nt2,'auto')
            h2 = drawROI( app.S.hROI, nt2 );
            mystr2 = append('Frame: ', num2str(frame)); %sprintf('MoCo Frame %i out of %i', frame, nFrames, 'fontweight', 'bold', 'fontsize', 14 );
            title( nt2, mystr2 );


        end

        % Button pushed function: viewStackButton
        function viewStackButtonPushed(app, event)
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            app.stackSlider.Limits = [ 1, size( app.S.stack, 3 ) ];
            switch app.stackSelectDropDown.Value
                case 'raw stack'
                    [minmax0(1), minmax0(2)] = minmax( app.S.stack );
                case 'MoCo aligned stack'
                    [minmax0(1), minmax0(2)] = minmax( app.S.Smoco.stack);
                case 'MoCo filtered stack'
                    [minmax0(1), minmax0(2)] = minmax( app.S.Smoco.Y);
                case 'norm stack'
                    [minmax0(1), minmax0(2)] = minmax( app.S.Smoco.normStack);

            end
            app.imageminmaxSlider.Limits = double(minmax0);
            app.imageminmaxSlider.Value = double(minmax0);

            stackSliderValueChanged(app,event)
        end

        % Value changed function: imageminmaxSlider, stackSlider
        function stackSliderValueChanged(app, event)
            frame = round(app.stackSlider.Value);
            app.frameEditField.Value = frame;
            xlimits = xlim( app.ax );
            ylimits = ylim( app.ax );
            nave = app.frameaverageEditField_2.Value;
            minmax = app.imageminmaxSlider.Value;
            if app.showROIsCheckBox_2.Value
                hROI = app.S.hROI;
            else
                hROI = [];
            end
            switch app.stackSelectDropDown.Value
                case 'raw stack'
                    app.im =stackViewer( app.ax, app.S.stack, nave, frame, hROI, minmax);
                case 'MoCo aligned stack'
                    app.im = stackViewer( app.ax, app.S.Smoco.stack, nave, frame, hROI, minmax);
                case 'MoCo filtered stack'
                    app.im = stackViewer( app.ax, app.S.Smoco.Y, nave, frame, hROI, minmax);
                case 'norm stack'
                    app.im = stackViewer( app.ax, app.S.Smoco.normStack, nave, frame, hROI, minmax);
            end
            if app.showROIsCheckBox_2.Value == 1
                % get ROI, deadzone, and peunumbra data
                data = getROIvalues( app.im, hROI );
                app.penumbraEditField.Value = data.penumbra;
                app.ROIEditField.Value = data.ROI;
                app.pensubEditField.Value = data.pensub;
            end
            if xlimits(1,2)~=1
                xlim( app.ax, xlimits );
                ylim( app.ax, ylimits );
            end
        end

        % Selection changed function: MoCoTypeButtonGroup
        function MoCoTypeButtonGroupSelectionChanged(app, event)

        end

        % Button pushed function: moveROIsButton
        function moveROIsButtonPushed(app, event)
            if app.moveROIstatus == 0
                app.moveROIsButton.BackgroundColor = [ 0, 1, 0 ];
                delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
                if (app.mocoButton.Value == 1) && isfield(app.S,'Smoco')
                    app.proj = MeanMaxSTD( app.S.Smoco.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
                else
                    app.proj = MeanMaxSTD( app.S.stack, app.projectionTypeButtonGroup.SelectedObject.Text );
                    app.mocoButton.Value = 0;
                end
                app.im = imshow(app.proj,'Parent',app.ax);
                clim(app.ax, 'auto')


                if isfield( app.S, 'hROI')
                    app.moveROIstatus = 1;
                    app.S.hROI = drawROI( app.S.hROI, app.ax );
                else
                    %disp('NO ROI FOR YOU! COME BACK ONE YEAR!')
                end
            else
                app.moveROIstatus = 0;
                app.moveROIsButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            thisTab = app.TabGroup.SelectedTab;

            switch thisTab.Title
                case 'stack viewer'
                     value = round(app.stackSlider.Value);
                     switch event.Key
                        case 'rightarrow'
                            value = value + 1;
                        case 'leftarrow'
                            value = value - 1;
                        case 'uparrow'
                            
                        case 'downarrow'
                            
                        otherwise
                    end
                    app.stackSlider.Value = round(value);
                    stackSliderValueChanged(app,event)
                case 'detect'
                    if strcmp(app.detectTabStatus, 'detect')
                        plotButtonPushed(app, event)
                    else
                        filterButtonPushed(app,event)
                    end
                case 'dF/F0'
                    confirmButtonPushed(app,event)
                case 'manual moco'

                    if app.moveROIstatus == 1
                        %                disp(event.Key)
                        stepSize = app.moveROIstepSpinner.Value;
                        % get the hROI handles
                        % move them according to key press
                        dx = 0;
                        dy = 0;
                        switch event.Key
                            case 'rightarrow'
                                dx = stepSize;
                            case 'leftarrow'
                                dx = -stepSize;
                            case 'uparrow'
                                dy = -stepSize;
                            case 'downarrow'
                                dy = stepSize;
                            otherwise
                        end
                        app.S.hROI = moveROIs( dx, dy, app.S.hROI, app.ax );
                    else
                    end
                otherwise
            end

        end

        % Callback function
        function showmaximaCheckBoxValueChanged(app, event)
            value = app.showmaximaCheckBox.Value;
            if value == 1
                % frames to average
                nFrames = size(app.S.stack,3);
                frame = app.mMoCoSlider.Value;
                nave = app.frameintervalEditField.Value;

                ts = frame;
                tend = frame+nave;
                if(tend>nFrames)
                    tend=nFrames;
                end
                Y1 = mean( app.S.stack( :, :, ts:tend ), 3 ); % average nave frames
                Y2 = mean( app.S.stack( :, :, ts+nave : tend+nave ), 3 ); % average nave frames
                spatialFilter = app.spatialfilterCheckBox.Value;
                if spatialFilter == 1
                    sigma = app.sigmaSpinner.Value;
                    hsize = app.hsizeSpinner.Value;
                    psf = fspecial('gaussian', round(2*hsize), sigma);
                    ind_nonzero = ( psf(:) >= max( psf(:,1) ) );
                    psf = psf - mean( psf( ind_nonzero ) );
                    psf( ~ind_nonzero ) = 0;   % only use pixels within the center disk
                    Y1 = imfilter( Y1, psf, 'symmetric' );
                    Y2 = imfilter( Y2, psf, 'symmetric' );
                end
                % get maxima from first frames average
                Y1max = imregionalmax( Y1 );
                % get maxima from second frame average
                Y2max = imregionalmax( Y2 );
                % display
                delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
                I = imfuse( Y1max, Y2max );
                imshow(I, 'Parent', app.ax );
            end
        end

        % Callback function
        function manualmocoTabButtonDown(app, event)
            app.mMoCoSlider.Limits = [ 1, size( app.S.stack, 3) ];
        end

        % Value changed function: mocochunkSpinner
        function mocochunkSpinnerValueChanged(app, event)
            value = app.mocochunkSpinner.Value;
            switch app.MoCoDispStatus
                case 'graphs'
                    MoCographsButtonPushed2(app, event)
                case 'template'
                    MocotemplatesButtonPushed(app,event)
                otherwise
                    disp(append('no moco disp status!',app.MoCoDispStatus))
            end
        end

        % Button pushed function: MocotemplatesButton
        function MocotemplatesButtonPushed(app, event)
            value = app.mocochunkSpinner.Value;
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );
            Ytemp = app.S.Smoco.S(value).template;
            imshow( Ytemp, 'Parent', app.ax );
            %clim(app.ax,'auto')
            app.MoCoDispStatus = 'template';
        end

        % Value changed function: frameEditField
        function frameEditFieldValueChanged(app, event)
            value = app.frameEditField.Value;
            app.stackSlider.Value = round(value);
            stackSliderValueChanged(app,event)
        end

        % Selection changed function: projectionTypeButtonGroup
        function projectionTypeButtonGroupSelectionChanged(app, event)

            app.Lamp.Color=[1,0,0]; drawnow
            %newROIsButtonPushed(app, event)
            
            delete(app.displayPanel.Children)
            pos = app.displayPanel.Position;
            app.ax = uiaxes(app.displayPanel,'Position', [0,0,pos(3),pos(4)] );

            projection_type = app.projectionTypeButtonGroup.SelectedObject.Text; % e.g. mean, std, max
            projection_group = app.projectionSourceButtonGroup.SelectedObject.Text;%'DFoF0';
            app.proj = app.S.projections.(projection_group).(projection_type);
            app.im = imshow(app.proj,'Parent',app.ax);
            minmax0=[0,100];
            crop_pixels = 10; % setting to ignore garbage on edges when setting range for image display

            % projection terminology differs from complete 
            signal_range = cleanDisplayRange( app.proj, crop_pixels ); % [min_signal,max_signal];


            [minmax0(1), minmax0(2)] = minmax( app.proj );
            app.Slider2.Limits = double(minmax0);
            app.Slider2.Value = double(signal_range); % double(minmax0);
         
            clim(app.ax, app.Slider2.Value )
            showROIsButtonPushed(app, event)

            app.Lamp.Color=[0,1,0]; drawnow
        end

        % Button pushed function: loadMatFileDir
        function loadMatFileDirButtonPushed(app, event)
            set(0,'DefaultFigureWindowStyle','normal')
            ff = fixfocus; %https://www.mathworks.com/matlabcentral/fileexchange/165961-fixfocus
            path = uigetdir("C:\Users\defazio\Documents\data\complete");
            delete(ff);
            % get list of files in dir
            codekey = 'complete*.mat';
            if ismac
                ext = append('*',codekey); %complete2.mat';
            elseif isunix
                % Code to run on Linux platform
            elseif ispc
                ext = append('\*',codekey); %complete.mat';
                the_slash='\';
            else
                disp('Platform not supported')
            end
            dpath = append(path,ext); % set the path with wildcard
            completeDir = dir(dpath);
            nfiles = size(completeDir,1);
            % loop over list
            namelist = cell(1,nfiles);
            filelist = cell(1,nfiles);
            for i=1:nfiles
                % load file complete
                filename = append(completeDir(i).folder, the_slash, completeDir(i).name);
                exp = '\d{8}[a-z]?_?\d*';
                name = regexp(completeDir(i).name,exp,'match','once');
                %disp(append('fn: ',filename, ' name: ', name))
                namelist{1,i} = char(name);
                filelist{1,i} = char(filename);
            end
            app.matfilesListBox.Items = namelist; % user friendly
            app.matfilesListBox.ItemsData = filelist; % full path
            app.matfilesListBox.Value=app.matfilesListBox.ItemsData{1,1};
            matfilesListBoxValueChanged(app, event)
            projectionTypeButtonGroupSelectionChanged(app, event)
        end

        % Value changed function: matfilesListBox
        function matfilesListBoxValueChanged(app, event)
            app.Lamp.Color=[1,0,0]; drawnow
            value = app.matfilesListBox.Value;
            app.dirEditField.Value = value;
            app.m = matfile(value,"Writable",true);
            % killing S here!!! and loading with minimum data from
            % selection
            app.S = loadFlatProj( app.m );
            %newROIsButtonPushed(app, event)
            showROIsButtonPushed(app, event)
            app.Lamp.Color=[0,1,0]; drawnow
        end

        % Clicked callback: matfilesListBox
        function matfilesListBoxClicked(app, event)
            matfilesListBoxValueChanged(app, event)
        end

        % Callback function
        function imageminmaxSlider_2ValueChanged(app, event)
            %value = app.imageminmaxSlider_2.Value;
             clim(app.ax, app.imageminmaxSlider_2.Value )
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            %value = app.Slider2.Value;
            
                         clim(app.ax, app.Slider2.Value )
        end

        % Value changed function: stepsize4Slider2
        function stepsize4Slider2ValueChanged(app, event)
            value = app.stepsize4Slider2.Value;
            app.Slider2.Step = value;
        end

        % Button pushed function: startROIButton
        function startROIButtonPushed(app, event)
            % get ROIs
            radius = app.radiusPixelsEditField.Value; % 10 pixels!!
            offset = app.deadzonePixelsEditField.Value; % 4 pixels!!
            penumbra = app.penumbraPixelsEditField.Value;
            bonus = app.bonusPixelsEditField.Value;
            sz = size( app.proj );
            n=app.maxNroisEditField.Value;
            %handles=[];
            for i=1:n
                % manual ROI, auto deadzone, penumbra

                [x,y]=selectDataPoint(app,app.ax); %ginput(1);
                if (x > 0) && (x <= sz(2)) && ( y> 0) && (y<=sz(1))
                    h = structuredROIax( x, y, app.proj, app.ax, radius, offset, penumbra, bonus );

                    if isstruct(h)
                        handles(i) = h;
                    else
                        disp('ROI selection did not return a handle!')
                        break
                    end
                else
                    % oustide of image!
                    break
                end
            end
            % if ~any([isfield(app.S.hROI,'mean'),isfield(app.S.hROI,'max'),isfield(app.S.hROI,'std')])
            %     app.S.hROI = [];
            % end

            % new roi storage for different projection types
            app.Lamp.Color=[1,0,0]; drawnow
            success=0;
            if exist('handles','var') && ~isempty(handles)
                if isfield(app.S,"hROI") && ~isfield(app.S,"old_hROI") % backup the old hROI, might want to compare at some point
                    app.S.old_hROI = app.S.hROI; % back up the old
                    app.m.old_hROI = app.S.old_hROI;
                end
                if isfield(app.S,"hROI") && isfield(app.S.hROI,"combo") % ADD ROIs
                    % combo exists, add ROIs
                    hROI = app.S.hROI;
                    nROIs = length(hROI.combo);
                    n_new_ROIs = length(handles);
                    for ii=1:n_new_ROIs
                        if isfield(hROI.combo, "projection_source")
                            handles(ii).projection_source = app.projectionSourceButtonGroup.SelectedObject.Text;
                            handles(ii).projection_type = app.projectionTypeButtonGroup.SelectedObject.Text;
                            hROI.combo( ii + nROIs ) = handles(ii);
                        else
                            hROI.combo( ii + nROIs ) = handles(ii);
                            hROI.combo(ii+nROIs).projection_source = app.projectionSourceButtonGroup.SelectedObject.Text;
                            hROI.combo(ii+nROIs).projection_type = app.projectionTypeButtonGroup.SelectedObject.Text;
                        end
                    end
                    app.S.hROI = hROI;
                    app.m.hROI = hROI;
                    success = 1;
                else %  create new hROI with handles
                    n_new_ROIs = length(handles);
                    for ii=1:n_new_ROIs % label the ROI origins
                        handles(ii).projection_source = app.projectionSourceButtonGroup.SelectedObject.Text;
                        handles(ii).projection_type = app.projectionTypeButtonGroup.SelectedObject.Text;
                    end
                    % app.S.hROI.(projection_type) = handles; % click2structuredROIsax( app.ax, app.proj, radius, offset );

                    % saves to combo directly now that we can see all
                    % the ROIs at once!
                    app.S.hROI.combo = handles; % click2structuredROIsax( app.ax, app.proj, radius, offset );
                    success=1;
                    app.m.hROI = app.S.hROI;
                end
            end

            if success
                disp(append("updated matfile! ", ...
                    app.projectionTypeButtonGroup.SelectedObject.Text, ...
                    "; ", app.projectionSourceButtonGroup.SelectedObject.Text,...
                    "; ", app.matfilesListBox.Value))
            else
                disp("failed to update matfile!")
            end
            app.Lamp.Color=[0,1,0]; drawnow
        
        end

        % Selection changed function: projectionSourceButtonGroup
        function projectionSourceButtonGroupSelectionChanged(app, event)
            projectionTypeButtonGroupSelectionChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Tooltip = {''};
            app.ROITab.Title = 'ROI';
            app.structureROIsButton = uibutton(app.ROITab, 'push');
            app.structureROIsButton.ButtonPushedFcn = createCallbackFcn(app, @structuredROIsButtonPushed, true);
            app.structureROIsButton.Text = 'structure ROIs';
            app.showROIsButton = uibutton(app.ROITab, 'push');
            app.showROIsButton.ButtonPushedFcn = createCallbackFcn(app, @showROIsButtonPushed, true);
            app.showROIsButton.Text = 'show ROIs';
            app.saveROIsButton = uibutton(app.ROITab, 'push');
            app.saveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @saveROIsButtonPushed, true);
            app.saveROIsButton.Text = 'save ROIs';
            app.ROItypeButtonGroup = uibuttongroup(app.ROITab);
            app.ROItypeButtonGroup.Title = 'ROI type';
            app.roundButton = uiradiobutton(app.ROItypeButtonGroup);
            app.roundButton.Text = 'round';
            app.roundButton.Value = true;
            app.structuredButton = uiradiobutton(app.ROItypeButtonGroup);
            app.structuredButton.Text = 'structured';
            app.radiuspixelsEditFieldLabel = uilabel(app.ROITab);
            app.radiuspixelsEditFieldLabel.Text = 'radius (pixels)';
            app.radiusPixelsEditField = uieditfield(app.ROITab, 'numeric');
            app.radiusPixelsEditField.Value = 10;
            app.deadzonepixelsLabel = uilabel(app.ROITab);
            app.deadzonepixelsLabel.Text = 'deadzone (pixels)';
            app.deadzonePixelsEditField = uieditfield(app.ROITab, 'numeric');
            app.deadzonePixelsEditField.Value = 4;
            app.startROIButton = uibutton(app.ROITab, 'push');
            app.startROIButton.ButtonPushedFcn = createCallbackFcn(app, @startROIButtonPushed, true);
            app.startROIButton.Text = 'start ROIs';
            app.newROIsButton = uibutton(app.ROITab, 'push');
            app.newROIsButton.ButtonPushedFcn = createCallbackFcn(app, @newROIsButtonPushed, true);
            app.newROIsButton.Text = 'new ROIs';
            app.moveROIsButton = uibutton(app.ROITab, 'push');
            app.moveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @moveROIsButtonPushed, true);
            app.moveROIsButton.Text = 'move ROIs';
            app.deadzonepixelsLabel_2 = uilabel(app.ROITab);
            app.deadzonepixelsLabel_2.Text = 'penumbra (pixels)';
            app.penumbraPixelsEditField = uieditfield(app.ROITab, 'numeric');
            app.penumbraPixelsEditField.Value = 2;
            app.movementsteppixelsSpinnerLabel = uilabel(app.ROITab);
            app.movementsteppixelsSpinnerLabel.Text = 'movement step (pixels)';
            app.moveROIstepSpinner = uispinner(app.ROITab);
            app.moveROIstepSpinner.Limits = [0 Inf];
            app.moveROIstepSpinner.ValueDisplayFormat = '%.0f';
            app.moveROIstepSpinner.Value = 2;
            app.deadzonepixelsLabel_3 = uilabel(app.ROITab);
            app.deadzonepixelsLabel_3.Text = 'bonus pixels';
            app.bonusPixelsEditField = uieditfield(app.ROITab, 'numeric');
            app.bonusPixelsEditField.Tooltip = {'increase (+) or decrease (-) the size of ROIs by pixels '};
            app.bonusPixelsEditField.Value = -2;
            app.deadzonepixelsLabel_4 = uilabel(app.ROITab);
            app.deadzonepixelsLabel_4.Text = 'max # ROIs';
            app.maxNroisEditField = uieditfield(app.ROITab, 'numeric');
            app.maxNroisEditField.Limits = [1 Inf];
            app.maxNroisEditField.RoundFractionalValues = 'on';
            app.maxNroisEditField.ValueDisplayFormat = '%.0f';
            app.maxNroisEditField.Tooltip = {'increase (+) or decrease (-) the size of ROIs by pixels '};
            app.maxNroisEditField.Value = 100;
            app.loadMatFileDir = uibutton(app.ROITab, 'push');
            app.loadMatFileDir.ButtonPushedFcn = createCallbackFcn(app, @loadMatFileDirButtonPushed, true);
            app.loadMatFileDir.Text = 'load matfile dir';
            app.matfilesListBoxLabel = uilabel(app.ROITab);
            app.matfilesListBoxLabel.Text = 'matfiles';
            app.matfilesListBox = uilistbox(app.ROITab);
            app.matfilesListBox.ValueChangedFcn = createCallbackFcn(app, @matfilesListBoxValueChanged, true);
            app.matfilesListBox.ClickedFcn = createCallbackFcn(app, @matfilesListBoxClicked, true);
            app.dirEditFieldLabel = uilabel(app.ROITab);
            app.dirEditFieldLabel.Text = 'dir';
            app.dirEditField = uieditfield(app.ROITab, 'text');
            app.Lamp = uilamp(app.ROITab);
            app.Lamp.Tooltip = {'working when ''red'''};
            app.loadTab = uitab(app.TabGroup);
            app.loadTab.Title = 'load';
            app.loadETSButton = uibutton(app.loadTab, 'push');
            app.loadETSButton.ButtonPushedFcn = createCallbackFcn(app, @loadETSButtonPushed2, true);
            app.loadETSButton.Text = 'load ETS';
            app.loadcompleteButton = uibutton(app.loadTab, 'push');
            app.loadcompleteButton.ButtonPushedFcn = createCallbackFcn(app, @loadcompleteButtonPushed, true);
            app.loadcompleteButton.Text = 'load "complete"';
            app.intervalmsEditFieldLabel = uilabel(app.loadTab);
            app.intervalmsEditFieldLabel.Text = 'interval (ms)';
            app.intervalmsEditField = uieditfield(app.loadTab, 'numeric');
            app.nameEditFieldLabel = uilabel(app.loadTab);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.loadTab, 'text');
            app.nameEditField.Editable = 'off';
            app.nameEditField.Value = 'datecode';
            app.mocoTab = uitab(app.TabGroup);
            app.mocoTab.Title = 'moco';
            app.MoCographsButton = uibutton(app.mocoTab, 'push');
            app.MoCographsButton.ButtonPushedFcn = createCallbackFcn(app, @MoCographsButtonPushed2, true);
            app.MoCographsButton.Text = 'MoCo graphs';
            app.MoCoButton = uibutton(app.mocoTab, 'push');
            app.MoCoButton.ButtonPushedFcn = createCallbackFcn(app, @MoCoButtonPushed2, true);
            app.MoCoButton.Text = 'MoCo';
            app.SliderLabel = uilabel(app.mocoTab);
            app.SliderLabel.Text = 'Slider';
            app.mocoConfirmSlider = uislider(app.mocoTab);
            app.mocoConfirmSlider.Limits = [1 100];
            app.mocoConfirmSlider.ValueChangedFcn = createCallbackFcn(app, @mocoConfirmSliderValueChanged, true);
            app.mocoConfirmSlider.MinorTicks = [1 2.5 4 5.5 7 8.5 10 11.5 13 14.5 16 17.5 19 20.5 22 23.5 25 26.5 28 29.5 31 32.5 34 35.5 37 38.5 40 41.5 43 44.5 46 47.5 49 50.5 52 53.5 55 56.5 58 59.5 61 62.5 64 65.5 67 68.5 70 71.5 73 74.5 76 77.5 79 80.5 82 83.5 85 86.5 88 89.5 91 92.5 94 95.5 97 98.5 100];
            app.mocoConfirmSlider.Value = 1;
            app.confirmMoCoButton = uibutton(app.mocoTab, 'push');
            app.confirmMoCoButton.ButtonPushedFcn = createCallbackFcn(app, @confirmMoCoButtonPushed, true);
            app.confirmMoCoButton.Text = 'confirm MoCo';
            app.frameaverageEditFieldLabel = uilabel(app.mocoTab);
            app.frameaverageEditFieldLabel.Text = 'frame average';
            app.frameaverageEditField = uieditfield(app.mocoTab, 'numeric');
            app.frameaverageEditField.Limits = [0 Inf];
            app.frameaverageEditField.ValueDisplayFormat = '%.0f';
            app.frameaverageEditField.Tooltip = {'0 is no averaging'};
            app.frameaverageEditField.Value = 100;
            app.maxshiftpixelsEditFieldLabel = uilabel(app.mocoTab);
            app.maxshiftpixelsEditFieldLabel.Text = 'max shift (pixels)';
            app.maxshiftEditField = uieditfield(app.mocoTab, 'numeric');
            app.maxshiftEditField.Limits = [1 Inf];
            app.maxshiftEditField.RoundFractionalValues = 'on';
            app.maxshiftEditField.ValueDisplayFormat = '%.0f';
            app.maxshiftEditField.Tooltip = {'original default 20'};
            app.maxshiftEditField.Value = 50;
            app.saveMoCofilteredstackCheckBox = uicheckbox(app.mocoTab);
            app.saveMoCofilteredstackCheckBox.Text = 'save  MoCo filtered stack';
            app.binwidthframesLabel = uilabel(app.mocoTab);
            app.binwidthframesLabel.Text = 'bin width (frames)';
            app.binwidthEditField = uieditfield(app.mocoTab, 'numeric');
            app.binwidthEditField.Limits = [1 Inf];
            app.binwidthEditField.RoundFractionalValues = 'on';
            app.binwidthEditField.ValueDisplayFormat = '%.0f';
            app.binwidthEditField.Tooltip = {'original default 20'};
            app.binwidthEditField.Value = 200;
            app.binwidthframesLabel_2 = uilabel(app.mocoTab);
            app.binwidthframesLabel_2.Text = 'non-rigid grid size (pixels)';
            app.gridSizeEditField = uieditfield(app.mocoTab, 'numeric');
            app.gridSizeEditField.Limits = [1 Inf];
            app.gridSizeEditField.RoundFractionalValues = 'on';
            app.gridSizeEditField.ValueDisplayFormat = '%.0f';
            app.gridSizeEditField.Tooltip = {'nofx on rigid! original default 20'};
            app.gridSizeEditField.Value = 100;
            app.chunksizeframesLabel = uilabel(app.mocoTab);
            app.chunksizeframesLabel.Text = 'chunk size (frames)';
            app.chunkSizeEditField = uieditfield(app.mocoTab, 'numeric');
            app.chunkSizeEditField.Limits = [1 Inf];
            app.chunkSizeEditField.RoundFractionalValues = 'on';
            app.chunkSizeEditField.ValueDisplayFormat = '%.0f';
            app.chunkSizeEditField.Tooltip = {'original default 20'};
            app.chunkSizeEditField.Value = 50000;
            app.MoCoDirectionsButtonGroup = uibuttongroup(app.mocoTab);
            app.MoCoDirectionsButtonGroup.Title = 'MoCo Direction(s)';
            app.forwardButton = uiradiobutton(app.MoCoDirectionsButtonGroup);
            app.forwardButton.Text = 'forward';
            app.forwardButton.Value = true;
            app.reverseButton = uiradiobutton(app.MoCoDirectionsButtonGroup);
            app.reverseButton.Text = 'reverse';
            app.forwardreverseButton = uiradiobutton(app.MoCoDirectionsButtonGroup);
            app.forwardreverseButton.Text = 'forward+reverse';
            app.reverseforwardButton = uiradiobutton(app.MoCoDirectionsButtonGroup);
            app.reverseforwardButton.Text = 'reverse+forward';
            app.MoCoTypeButtonGroup = uibuttongroup(app.mocoTab);
            app.MoCoTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoCoTypeButtonGroupSelectionChanged, true);
            app.MoCoTypeButtonGroup.Title = 'MoCo Type';
            app.rigidButton = uiradiobutton(app.MoCoTypeButtonGroup);
            app.rigidButton.Text = 'rigid';
            app.rigidButton.Value = true;
            app.nonrigidButton = uiradiobutton(app.MoCoTypeButtonGroup);
            app.nonrigidButton.Text = 'non-rigid';
            app.mocochunkSpinnerLabel = uilabel(app.mocoTab);
            app.mocochunkSpinnerLabel.Text = 'moco chunk';
            app.mocochunkSpinner = uispinner(app.mocoTab);
            app.mocochunkSpinner.Limits = [1 Inf];
            app.mocochunkSpinner.ValueChangedFcn = createCallbackFcn(app, @mocochunkSpinnerValueChanged, true);
            app.mocochunkSpinner.Value = 1;
            app.MocotemplatesButton = uibutton(app.mocoTab, 'push');
            app.MocotemplatesButton.ButtonPushedFcn = createCallbackFcn(app, @MocotemplatesButtonPushed, true);
            app.MocotemplatesButton.Text = 'Moco templates';
            app.binwidthframesLabel_3 = uilabel(app.mocoTab);
            app.binwidthframesLabel_3.Text = 'bounds (pixels)';
            app.boundspixelsEditField = uieditfield(app.mocoTab, 'numeric');
            app.boundspixelsEditField.Limits = [0 Inf];
            app.boundspixelsEditField.RoundFractionalValues = 'on';
            app.boundspixelsEditField.ValueDisplayFormat = '%.0f';
            app.boundspixelsEditField.Tooltip = {'nofx on rigid! original default 20'};
            app.boundspixelsEditField.Value = 20;
            app.stackviewerTab = uitab(app.TabGroup);
            app.stackviewerTab.Title = 'stack viewer';
            app.viewStackButton = uibutton(app.stackviewerTab, 'push');
            app.viewStackButton.ButtonPushedFcn = createCallbackFcn(app, @viewStackButtonPushed, true);
            app.viewStackButton.Text = 'view stack';
            app.Slider_2Label = uilabel(app.stackviewerTab);
            app.Slider_2Label.Text = 'Slider';
            app.stackSlider = uislider(app.stackviewerTab);
            app.stackSlider.Limits = [1 100];
            app.stackSlider.ValueChangedFcn = createCallbackFcn(app, @stackSliderValueChanged, true);
            app.stackSlider.MinorTicks = [1 2.5 4 5.5 7 8.5 10 11.5 13 14.5 16 17.5 19 20.5 22 23.5 25 26.5 28 29.5 31 32.5 34 35.5 37 38.5 40 41.5 43 44.5 46 47.5 49 50.5 52 53.5 55 56.5 58 59.5 61 62.5 64 65.5 67 68.5 70 71.5 73 74.5 76 77.5 79 80.5 82 83.5 85 86.5 88 89.5 91 92.5 94 95.5 97 98.5 100];
            app.stackSlider.Value = 1;
            app.frameaverageEditField_2Label = uilabel(app.stackviewerTab);
            app.frameaverageEditField_2Label.Text = 'frame average';
            app.frameaverageEditField_2 = uieditfield(app.stackviewerTab, 'numeric');
            app.frameaverageEditField_2.Limits = [0 Inf];
            app.frameaverageEditField_2.ValueDisplayFormat = '%.0f';
            app.frameaverageEditField_2.Tooltip = {'0 is no averaging'};
            app.DropDownLabel = uilabel(app.stackviewerTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.stackSelectDropDown = uidropdown(app.stackviewerTab);
            app.stackSelectDropDown.Items = {'raw stack', 'MoCo aligned stack', 'MoCo filtered stack', 'norm stack'};
            app.stackSelectDropDown.Value = 'raw stack';
            app.imageminmaxSliderLabel = uilabel(app.stackviewerTab);
            app.imageminmaxSliderLabel.Text = 'image min/max';
            app.imageminmaxSlider = uislider(app.stackviewerTab, 'range');
            app.imageminmaxSlider.Limits = [0 65000];
            app.imageminmaxSlider.ValueChangedFcn = createCallbackFcn(app, @stackSliderValueChanged, true);
            app.imageminmaxSlider.Value = [0 65000];
            app.showROIsCheckBox_2 = uicheckbox(app.stackviewerTab);
            app.showROIsCheckBox_2.Text = 'show ROIs';
            app.frameEditFieldLabel = uilabel(app.stackviewerTab);
            app.frameEditFieldLabel.Text = 'frame';
            app.frameEditField = uieditfield(app.stackviewerTab, 'numeric');
            app.frameEditField.ValueChangedFcn = createCallbackFcn(app, @frameEditFieldValueChanged, true);
            app.ROIEditFieldLabel = uilabel(app.stackviewerTab);
            app.ROIEditFieldLabel.Text = 'ROI';
            app.ROIEditField = uieditfield(app.stackviewerTab, 'numeric');
            app.ROIEditField.Editable = 'off';
            app.pensubLabel = uilabel(app.stackviewerTab);
            app.pensubLabel.Text = 'pensub';
            app.pensubEditField = uieditfield(app.stackviewerTab, 'numeric');
            app.pensubEditField.Editable = 'off';
            app.penumbraEditFieldLabel = uilabel(app.stackviewerTab);
            app.penumbraEditFieldLabel.Text = 'penumbra';
            app.penumbraEditField = uieditfield(app.stackviewerTab, 'numeric');
            app.penumbraEditField.Editable = 'off';
            app.dFF0Tab = uitab(app.TabGroup);
            app.dFF0Tab.Tooltip = {'fraction of penumbra to subtract from ROI'};
            app.dFF0Tab.Title = 'dF/F0';
            app.datafromROIsButton = uibutton(app.dFF0Tab, 'push');
            app.datafromROIsButton.ButtonPushedFcn = createCallbackFcn(app, @datafromROIsButtonPushed, true);
            app.datafromROIsButton.Text = 'data from ROIs';
            app.updateROIdataButton = uibutton(app.dFF0Tab, 'push');
            app.updateROIdataButton.ButtonPushedFcn = createCallbackFcn(app, @updateROIdataButtonPushed, true);
            app.updateROIdataButton.Text = 'update ROIdata';
            app.dFoF0Button = uibutton(app.dFF0Tab, 'push');
            app.dFoF0Button.ButtonPushedFcn = createCallbackFcn(app, @dFoF0ButtonPushed, true);
            app.dFoF0Button.Text = 'dFoF0';
            app.t1smoothsEditFieldLabel = uilabel(app.dFF0Tab);
            app.t1smoothsEditFieldLabel.Text = 't1 smooth (s)';
            app.t1smoothsEditField = uieditfield(app.dFF0Tab, 'numeric');
            app.t1smoothsEditField.Value = 10;
            app.t2minsearchsEditFieldLabel = uilabel(app.dFF0Tab);
            app.t2minsearchsEditFieldLabel.Text = 't2 min search (s)';
            app.t2minsearchsEditField = uieditfield(app.dFF0Tab, 'numeric');
            app.t2minsearchsEditField.Value = Inf;
            app.notimplementedt3sEditFieldLabel = uilabel(app.dFF0Tab);
            app.notimplementedt3sEditFieldLabel.Text = 'not implemented t3 (s)';
            app.notimplementedt3sEditField = uieditfield(app.dFF0Tab, 'numeric');
            app.notimplementedt3sEditField.Value = Inf;
            app.t4largeeventdurationsEditFieldLabel = uilabel(app.dFF0Tab);
            app.t4largeeventdurationsEditFieldLabel.Text = 't4 large event duration (s)';
            app.t4largeeventdurationsEditField = uieditfield(app.dFF0Tab, 'numeric');
            app.t4largeeventdurationsEditField.Value = 200;
            app.confirmButton = uibutton(app.dFF0Tab, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Text = 'confirm';
            app.minminBFrawFlLabel = uilabel(app.dFF0Tab);
            app.minminBFrawFlLabel.Text = 'fraction of penumbra';
            app.fractionPenumbraEditField = uieditfield(app.dFF0Tab, 'numeric');
            app.fractionPenumbraEditField.Limits = [0 Inf];
            app.fractionPenumbraEditField.Value = 0.7;
            app.detectTab = uitab(app.TabGroup);
            app.detectTab.Title = 'detect';
            app.ROIdatadirButton = uibutton(app.detectTab, 'push');
            app.ROIdatadirButton.ButtonPushedFcn = createCallbackFcn(app, @ROIdatadirButtonPushed, true);
            app.ROIdatadirButton.Tooltip = {'_Igor'};
            app.ROIdatadirButton.Text = 'ROIdata dir';
            app.EditField = uieditfield(app.detectTab, 'text');
            app.SDrollsSpinnerLabel = uilabel(app.detectTab);
            app.SDrollsSpinnerLabel.Text = 'SD roll (s)';
            app.SDrollsSpinner = uispinner(app.detectTab);
            app.SDrollsSpinner.Value = 10;
            app.SDpercentileSpinnerLabel = uilabel(app.detectTab);
            app.SDpercentileSpinnerLabel.Text = 'SD percentile';
            app.SDpercentileSpinner = uispinner(app.detectTab);
            app.SDpercentileSpinner.Value = 40;
            app.SDfactorSpinnerLabel = uilabel(app.detectTab);
            app.SDfactorSpinnerLabel.Text = 'SD factor';
            app.SDfactorSpinner = uispinner(app.detectTab);
            app.SDfactorSpinner.Value = 10;
            app.minwidthsSpinnerLabel = uilabel(app.detectTab);
            app.minwidthsSpinnerLabel.Text = 'min width (s)';
            app.minwidthsSpinner = uispinner(app.detectTab);
            app.minwidthsSpinner.Limits = [-99.5 100.5];
            app.minwidthsSpinner.Value = 0.5;
            app.detectButton = uibutton(app.detectTab, 'push');
            app.detectButton.ButtonPushedFcn = createCallbackFcn(app, @detectButtonPushed, true);
            app.detectButton.Text = 'detect';
            app.filterButton = uibutton(app.detectTab, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Text = 'filter';
            app.samplerateHzEditFieldLabel = uilabel(app.detectTab);
            app.samplerateHzEditFieldLabel.Text = 'sample rate (Hz)';
            app.samplerateHzEditField = uieditfield(app.detectTab, 'numeric');
            app.samplerateHzEditField.Editable = 'off';
            app.samplerateHzEditField.Value = 10;
            app.orderSpinnerLabel = uilabel(app.detectTab);
            app.orderSpinnerLabel.Text = 'order';
            app.orderSpinner = uispinner(app.detectTab);
            app.orderSpinner.ValueChangedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.orderSpinner.Value = 7;
            app.stopfreqHzLabel = uilabel(app.detectTab);
            app.stopfreqHzLabel.Text = 'stop freq (Hz)';
            app.stopfreqHzSpinner = uispinner(app.detectTab);
            app.stopfreqHzSpinner.ValueChangedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.stopfreqHzSpinner.Value = 1;
            app.plotButton = uibutton(app.detectTab, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.presSpinnerLabel = uilabel(app.detectTab);
            app.presSpinnerLabel.Text = 'pre (s)';
            app.presSpinner = uispinner(app.detectTab);
            app.presSpinner.Limits = [1 Inf];
            app.presSpinner.ValueDisplayFormat = '%.0f';
            app.presSpinner.Value = 10;
            app.postsSpinnerLabel = uilabel(app.detectTab);
            app.postsSpinnerLabel.Text = 'post (s)';
            app.postsSpinner = uispinner(app.detectTab);
            app.postsSpinner.Limits = [1 Inf];
            app.postsSpinner.ValueDisplayFormat = '%.0f';
            app.postsSpinner.Value = 100;
            app.eventsTab = uitab(app.TabGroup);
            app.eventsTab.Title = 'events';
            app.preSpinnerLabel = uilabel(app.eventsTab);
            app.preSpinnerLabel.Text = 'pre';
            app.preSpinner = uispinner(app.eventsTab);
            app.preSpinner.Value = 100;
            app.postSpinnerLabel = uilabel(app.eventsTab);
            app.postSpinnerLabel.Text = 'post';
            app.postSpinner = uispinner(app.eventsTab);
            app.postSpinner.Value = 500;
            app.getvarsTab = uitab(app.TabGroup);
            app.getvarsTab.Title = 'get vars';
            app.checkvarsButton = uibutton(app.getvarsTab, 'push');
            app.checkvarsButton.ButtonPushedFcn = createCallbackFcn(app, @checkvarsButtonPushed, true);
            app.checkvarsButton.Text = 'check vars';
            app.loadvarsButton = uibutton(app.getvarsTab, 'push');
            app.loadvarsButton.ButtonPushedFcn = createCallbackFcn(app, @loadvarsButtonPushed, true);
            app.loadvarsButton.Text = 'load vars';
            app.displayPanel = uipanel(app.GridLayout);
            app.displayPanel.Title = 'display';
            app.globalsettingsPanel = uipanel(app.GridLayout);
            app.globalsettingsPanel.Title = 'global settings';
            app.projectionSourceButtonGroup = uibuttongroup(app.globalsettingsPanel);
            app.projectionSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @projectionSourceButtonGroupSelectionChanged, true);
            app.rawButton = uiradiobutton(app.projectionSourceButtonGroup);
            app.rawButton.Text = 'raw';
            app.mocoButton = uiradiobutton(app.projectionSourceButtonGroup);
            app.mocoButton.Text = 'moco';
            app.DFoF0Button = uiradiobutton(app.projectionSourceButtonGroup);
            app.DFoF0Button.Text = 'DFoF0';
            app.DFoF0Button.Value = true;
            app.maxprojectionButton = uibutton(app.globalsettingsPanel, 'push');
            app.maxprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @maxprojectionButtonPushed2, true);
            app.maxprojectionButton.Text = 'max projection';
            app.fromworkspaceButton = uibutton(app.globalsettingsPanel, 'push');
            app.fromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @fromworkspaceButtonPushed, true);
            app.fromworkspaceButton.Text = 'from workspace';
            app.toworkspaceButton = uibutton(app.globalsettingsPanel, 'push');
            app.toworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @toworkspaceButtonPushed2, true);
            app.toworkspaceButton.Text = 'to workspace';
            app.projectionTypeButtonGroup = uibuttongroup(app.globalsettingsPanel);
            app.projectionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @projectionTypeButtonGroupSelectionChanged, true);
            app.meanButton = uiradiobutton(app.projectionTypeButtonGroup);
            app.meanButton.Text = 'mean';
            app.meanButton.Value = true;
            app.maxButton = uiradiobutton(app.projectionTypeButtonGroup);
            app.maxButton.Text = 'max';
            app.stdButton = uiradiobutton(app.projectionTypeButtonGroup);
            app.stdButton.Text = 'std';
            app.displaycontrolPanel = uipanel(app.GridLayout);
            app.displaycontrolPanel.Title = 'display control';
            app.xstartEditFieldLabel = uilabel(app.displaycontrolPanel);
            app.xstartEditFieldLabel.Text = 'xstart';
            app.xstartEditField = uieditfield(app.displaycontrolPanel, 'numeric');
            app.xendEditFieldLabel = uilabel(app.displaycontrolPanel);
            app.xendEditFieldLabel.Text = 'xend';
            app.xendEditField = uieditfield(app.displaycontrolPanel, 'numeric');
            app.xendEditField.Value = Inf;
            app.iROISpinner_2Label = uilabel(app.displaycontrolPanel);
            app.iROISpinner_2Label.Text = 'iROI';
            app.iROISpinner_2 = uispinner(app.displaycontrolPanel);
            app.iROISpinner_2.Limits = [1 Inf];
            app.iROISpinner_2.ValueDisplayFormat = '%.0f';
            app.iROISpinner_2.ValueChangedFcn = createCallbackFcn(app, @iROISpinner_2ValueChanged, true);
            app.iROISpinner_2.Value = 6;
            app.iFileSpinner_2 = uispinner(app.displaycontrolPanel);
            app.iFileSpinner_2.Limits = [1 Inf];
            app.iFileSpinner_2.ValueChangedFcn = createCallbackFcn(app, @iFileSpinner_2ValueChanged, true);
            app.iFileSpinner_2.Value = 1;
            app.iFileLabel = uilabel(app.displaycontrolPanel);
            app.iFileLabel.Text = 'iFile';
            app.ButtonGroup = uibuttongroup(app.displaycontrolPanel);
            app.allButton = uiradiobutton(app.ButtonGroup);
            app.allButton.Text = 'all';
            app.singleROIButton = uiradiobutton(app.ButtonGroup);
            app.singleROIButton.Text = 'single ROI';
            app.singleROIButton.Value = true;
            app.singlefileButton = uiradiobutton(app.ButtonGroup);
            app.singlefileButton.Text = 'single file';
            app.getlimitsButton = uibutton(app.displaycontrolPanel, 'push');
            app.getlimitsButton.ButtonPushedFcn = createCallbackFcn(app, @getlimitsButtonPushed, true);
            app.getlimitsButton.Text = 'get limits';
            app.Slider2Label = uilabel(app.displaycontrolPanel);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.displaycontrolPanel, 'range');
            app.Slider2.Limits = [0 1];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.Value = [0 1];
            app.stepsizeLabel = uilabel(app.displaycontrolPanel);
            app.stepsizeLabel.Text = 'step size';
            app.stepsize4Slider2 = uieditfield(app.displaycontrolPanel, 'numeric');
            app.stepsize4Slider2.Limits = [0 Inf];
            app.stepsize4Slider2.ValueChangedFcn = createCallbackFcn(app, @stepsize4Slider2ValueChanged, true);
            app.stepsize4Slider2.Value = 1e-21;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aCa3_ROIs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end